classdef yunsuan_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        UIAxes             matlab.ui.control.UIAxes
        x1Button           matlab.ui.control.Button
        DropDown           matlab.ui.control.DropDown
        aEditFieldLabel    matlab.ui.control.Label
        aEditField         matlab.ui.control.NumericEditField
        bEditFieldLabel    matlab.ui.control.Label
        bEditField         matlab.ui.control.NumericEditField
        DropDown_2         matlab.ui.control.DropDown
        aEditField_2Label  matlab.ui.control.Label
        aEditField_2       matlab.ui.control.NumericEditField
        bEditField_2Label  matlab.ui.control.Label
        bEditField_2       matlab.ui.control.NumericEditField
        x2Button           matlab.ui.control.Button
        UIAxes4            matlab.ui.control.UIAxes
        x1x2Button         matlab.ui.control.Button
        DropDown_3         matlab.ui.control.DropDown
        UIAxes2            matlab.ui.control.UIAxes
        Tab_2              matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Callback function
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x;
            global t;
            global flag1;
            flag1=1;
            a = str2num(app.edita1.Value);
            b = str2num(app.editb1.Value);
            t=eval(app.edit5.Value);
            choose1 = app.popupmenu1.Value;   % 得到我选择的函数
%             min=t(1);
%             max=t(end);
            
            % 如果a b都是空的那么就返回
             if (isempty(a)||isempty(b))
                 msgbox('input error','warn','warn');
                 return;
             end
            
            % 修改%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            eval(['x=',choose1,';']);
%             switch choose1
%                 case 'a*sin(b*t)'
%                     x=a*sin(b*t);
%                 case 2
%                     x=a*cos(b*t);
%                 case 'a*exp(b*t)'
%                     x=a*exp(b*t);
%                 case 4
%                     x=a*t+b;
%                 case 5
%                     x=HEAVISIDE(t);
%                     flag1=0;
%             end
            plot(app.axes1,t,x);
%             if flag1==0
%                 axis([min max -0.5 1.5])
%             end
        end

        % Callback function
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global y;
            global t;
            global flag1;
            flag2=1;
            a = str2num(app.edita2.Value);
            b = str2num(app.editb2.Value);
            t=eval(app.edit5.Value);
            choose2 = app.popupmenu2.Value;   % 得到我选择的函数
            min=t(1);
            max=t(end);
            
            % 如果a b都是空的那么就返回
            if (isempty(a)||isempty(b))
                msgbox('input error','warn','warn');
                return;
            end
            
            eval(['y=',choose2,';']); 
            % 修改%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             switch choose2
%                 case 1
%                     y=a*sin(b*t);
%                 case 2
%                     y=a*cos(b*t);
%                 case 3
%                     y=a*exp(b*t);
%                 case 4
%                     y=a*t+b;
%                 case 5
%                     y=HEAVISIDE(t);
%                     flag1=0;
%             end
            plot(app.axes2,t,y);
%             if flag2==0
%                 axis([min max -0.5 1.5])
%             end
        end

        % Callback function
        function pushbutton3_Callback(app, event)
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x;
            global y;
            global t;
            global flag1;
            global flag2;
            t=eval(app.edit5.Value);
            min=t(1);
            max=t(end);
            if isempty(x)
                msgbox('select x1 first','warn','warn');
                return
            end
            if isempty(y)
                 msgbox('select x2 first ','warn','warn');
                return
            end
            choose = get(app.popupmenu3,'value');
%             switch choose
%                 case 1
%                     f=x+y;
%                 case 2
%                     f=x.*y;
%             end
            eval(['f=x',choose,'y;']);
            plot(app.axes3,t,f);
%             if (flag1+flag2)==0
%                 axis([min max -0.5 2.5]);
%             end
        end

        % Callback function
        function pushbutton4_Callback(app, event)
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global t;
            global flag;
            global Ft;
            flag=1;
            a = str2num(get(app.edita3,'String'));
            b = str2num(get(app.editb3,'String'));
            t=eval(get(app.edit5,'String'));
            choice = get(app.popupmenu4,'value');
            if (isempty(a)|isempty(b))
                 msgbox('input error','warn','warn');
                 return;
            end
            guidata(hObject, handles);
            min=t([1]);
            max=t([length(t)]);
            switch choice
                case 1
                    Ft=a*sin(b*t);
                case 2
                    Ft=a*cos(b*t);
                case 3
                    Ft=a*exp(b*t);
                case 4
                    Ft=a*t+b;
                case 5
                    Ft=HEAVISIDE(t);
                    flag=0;
            end
            axes(app.axes4);
            plot(t,Ft);
            grid on
            if flag==0
                axis([min max -0.5 1.5])
            end
        end

        % Callback function
        function pushbutton8_Callback(app, event)
            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            delete(app);
        end

        % Callback function
        function pushbutton_fanzhuan_Callback(app, event)
            % hObject    handle to pushbutton_fanzhuan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global t;
            global Ft;
            global flag;
            t=eval(get(app.edit5,'String'));
            min=t([1]);
            max=t([length(t)]);
            if isempty(Ft)
                 msgbox('select x first','warn','warn');
                return
            end
            t=-t;
            axes(app.axes5);
            plot(t,Ft);
            grid on
            if flag==0
                axis([min max -0.5 1.5])
            end
        end

        % Callback function
        function pushbutton_scale_Callback(app, event)
            % hObject    handle to pushbutton_scale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global t;
            global Ft;
            global flag;
            m = str2num(get(app.edit_scale,'String'));
            if (isempty(m))
                 msgbox('input error','warn','warn');
                 return;
            end
            guidata(hObject, handles);
            t=eval(get(app.edit5,'String'));
            choice_scale = get(app.popupmenu_scale,'value');
            min=t([1]);
            max=t([length(t)]);
            if isempty(Ft)
                  msgbox('select x first','warn','warn');
                return
            end
            switch choice_scale
                case 1
                    t=t/m;
                case 2
                    t=t*m;
            end
            axes(app.axes5);
            plot(t,Ft);
            axis([min max -20 20])
            axis 'auto y'
            grid on
            if flag==0
                axis([min max -0.5 1.5])
            end
        end

        % Callback function
        function pushbutton_yiwei_Callback(app, event)
            % hObject    handle to pushbutton_yiwei (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global t;
            global Ft;
            global flag;
            N = str2double(get(app.edit_yiwei,'String'));
            if (isempty(N))
                 msgbox('input error','warn','warn');
                 return;
            end
            guidata(hObject, handles);
            t=eval(get(app.edit5,'String'));
            choice_yiwei = get(app.popupmenu_yiwei,'value');
            min=t([1]);
            max=t([length(t)]);
            if isempty(Ft)
                 msgbox('select x first','warn','warn');
                return
            end
            switch choice_yiwei
                case 1
                    t=t-N;
                case 2
                    t=t+N;
            end
            axes(app.axes5);
            plot(t,Ft);
            axis([min-N max+N -20 20])
            axis 'auto y'
            grid on
            if flag==0
                axis([min max -0.5 1.5])
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = '信号的运算';
            app.TabGroup = uitabgroup(app.figure1);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '连续';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.x1Button = uibutton(app.Tab, 'push');
            app.x1Button.Text = '画出x1图像';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'a*sin(b*x)', 'a*cos(b*x)'};
            app.DropDown.Value = 'a*sin(b*x)';
            app.aEditFieldLabel = uilabel(app.Tab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Tab, 'numeric');
            app.bEditFieldLabel = uilabel(app.Tab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Tab, 'numeric');
            app.DropDown_2 = uidropdown(app.Tab);
            app.DropDown_2.Items = {'a*sin(b*x)', 'a*cos(b*x)', ''};
            app.DropDown_2.Value = 'a*sin(b*x)';
            app.aEditField_2Label = uilabel(app.Tab);
            app.aEditField_2Label.Text = 'a';
            app.aEditField_2 = uieditfield(app.Tab, 'numeric');
            app.bEditField_2Label = uilabel(app.Tab);
            app.bEditField_2Label.Text = 'b';
            app.bEditField_2 = uieditfield(app.Tab, 'numeric');
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.x2Button = uibutton(app.Tab, 'push');
            app.x2Button.Text = '画出x2图像';
            app.DropDown_3 = uidropdown(app.Tab);
            app.DropDown_3.Items = {'+', '.*'};
            app.DropDown_3.Value = '+';
            app.UIAxes4 = uiaxes(app.Tab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.x1x2Button = uibutton(app.Tab, 'push');
            app.x1x2Button.Text = 'x1与x2运算';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '离散';
        end
    end

    methods (Access = public)

        % Construct app
        function app = yunsuan_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end